classdef NDN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NatDyNetUIFigure        matlab.ui.Figure
        GetROITimeCourseButton  matlab.ui.control.Button
        DynamicMethodsLabel     matlab.ui.control.Label
        StaticMethodsLabel      matlab.ui.control.Label
        ISDCCButton_6           matlab.ui.control.Button
        ISDCCButton_5           matlab.ui.control.Button
        ISDCCButton_4           matlab.ui.control.Button
        ISDCCButton_3           matlab.ui.control.Button
        SFCButton               matlab.ui.control.Button
        NatDyNetNatureDynamicsNetworkLabel  matlab.ui.control.Label
        ISCAPButton             matlab.ui.control.Button
        ISCButton               matlab.ui.control.Button
    end


    properties (Access = public)
        ISCapp % Description
        SFCapp
        GetROITCapp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Welcome to using NatDyNet--Nature Dynamics Network toolbox')
        end

        % Close request function: NatDyNetUIFigure
        function NatDyNetUIFigureCloseRequest(app, event)
            delete(app.ISCapp)
            delete(app.SFCapp)
            delete(app.GetROITCapp)
            delete(app)
            disp('Thank you for using Nature Dynamics Network')
        end

        % Button pushed function: GetROITimeCourseButton
        function GetROITimeCourseButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.GetROITimeCourseButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.GetROITCapp = GetROITC_GUI(app);
        end

        % Button pushed function: ISCButton
        function ISCButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ISCButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.ISCapp = ISC_GUI(app);
        end

        % Button pushed function: SFCButton
        function SFCButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SFCButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.SFCapp = SFCROI_GUI(app);
        end

        % Button pushed function: ISCAPButton
        function ISCAPButtonPushed(app, event)
                        % Disable Plot Options button while dialog is open
            app.ISCAPButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.ISCapp = ISCAP_GUI(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NatDyNetUIFigure = uifigure('Visible', 'off');
            app.NatDyNetUIFigure.Name = 'NatDyNet';
            app.NatDyNetUIFigure.CloseRequestFcn = createCallbackFcn(app, @NatDyNetUIFigureCloseRequest, true);
            app.ISCButton = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISCButton.ButtonPushedFcn = createCallbackFcn(app, @ISCButtonPushed, true);
            app.ISCButton.Text = 'Intersubject Correlation(ISC)';
            app.ISCAPButton = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISCAPButton.ButtonPushedFcn = createCallbackFcn(app, @ISCAPButtonPushed, true);
            app.ISCAPButton.Text = 'Intersubject Co-activation Pattern(ISCAP)';
            app.NatDyNetNatureDynamicsNetworkLabel = uilabel(app.NatDyNetUIFigure);
            app.NatDyNetNatureDynamicsNetworkLabel.Text = 'NatDyNet--Nature Dynamics Network';
            app.SFCButton = uibutton(app.NatDyNetUIFigure, 'push');
            app.SFCButton.ButtonPushedFcn = createCallbackFcn(app, @SFCButtonPushed, true);
            app.SFCButton.Text = 'Static Funtional Connectivity(SFC)';
            app.ISDCCButton_3 = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISDCCButton_3.Text = 'Intersubject Dynamic Condition Correlation';
            app.ISDCCButton_4 = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISDCCButton_4.Text = 'Intersubject Funtional Connectivity(ISFC)';
            app.ISDCCButton_5 = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISDCCButton_5.Text = 'Dynamics InterSubject Correlation(DISC)';
            app.ISDCCButton_6 = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISDCCButton_6.Text = 'Dynamic Funtional Connectivity(DFC)';
            app.StaticMethodsLabel = uilabel(app.NatDyNetUIFigure);
            app.StaticMethodsLabel.Text = '1.Static Methods';
            app.DynamicMethodsLabel = uilabel(app.NatDyNetUIFigure);
            app.DynamicMethodsLabel.Text = '2.Dynamic Methods';
            app.GetROITimeCourseButton = uibutton(app.NatDyNetUIFigure, 'push');
            app.GetROITimeCourseButton.ButtonPushedFcn = createCallbackFcn(app, @GetROITimeCourseButtonPushed, true);
            app.GetROITimeCourseButton.Text = 'Get ROI Time Course';
            % Show the figure after all components are created
            app.NatDyNetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NDN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NatDyNetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NatDyNetUIFigure)
        end
    end
end