classdef ISC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISCFigure               matlab.ui.Figure
        SavedDirBtn             matlab.ui.control.Button
        e_SavedDirEditField     matlab.ui.control.EditField
        SavedDirEditFieldLabel  matlab.ui.control.Label
        InterSubjectCorrelationISCLabel  matlab.ui.control.Label
        ProgrssBarLabel         matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        GMBtn                   matlab.ui.control.Button
        subdirPrefixEditField   matlab.ui.control.EditField
        subdirPrefixLabel       matlab.ui.control.Label
        e_GMmaskfileEditField   matlab.ui.control.EditField
        PrefixLabel             matlab.ui.control.Label
        e_InputDirEditField     matlab.ui.control.EditField
        InputDirLabel           matlab.ui.control.Label
        InputDirBtn             matlab.ui.control.Button
        RunBtn                  matlab.ui.control.Button
    end


    properties (Access = public)
        mainApp % 主界面对象
        ax %进度条对象
        isCreateAx %作为一个flag，判断进度条对象是否创建
        runned %作为一个flag，判断是否按过run
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mainApp=caller;
            app.e_InputDirEditField.Value = pwd;
            app.isCreateAx = false;
            app.runned = false;
            app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, 'ISC-Result');
            assignin('base', 'ISC_app', app);
            %             app.UI
        end

        % Close request function: ISCFigure
        function ISCFigureCloseRequest(app, event)
            app.mainApp.ISCButton.Enable = 'on';
            app.mainApp.GMvoxelMethodsDropDown.Enable = "on";
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: InputDirBtn
        function InputDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCFigure.Visible = 'off';
            app.mainApp.NatDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end


            [inputDir] = uigetdir('*.*','Please select an input directory');
            if inputDir ~= 0
                app.e_InputDirEditField.Value = string(inputDir);
                app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, 'ISC-Result');
                app.subdirPrefixEditField.Value = '';
                app.Label.Text = '';
            end

            app.mainApp.NatDyNetUIFigure.Visible = 'on';
            app.ISCFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: SavedDirBtn
        function SavedDirBtnButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCFigure.Visible = 'off';
            app.mainApp.NatDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [inputDir] = uigetdir('*.*','Please select a saved directory');
            if inputDir ~= 0
                app.e_SavedDirEditField.Value = string(inputDir);
            end

            app.mainApp.NatDyNetUIFigure.Visible = 'on';
            app.ISCFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: GMBtn
        function GMBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCFigure.Visible = 'off';
            app.mainApp.NatDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [filename, pathname] = uigetfile('*.*','Please select a Grey Matter mask file');
            if filename ~= 0
                maskAddress = fullfile(pathname, filename);
                app.e_GMmaskfileEditField.Value = string(maskAddress);
            end

            app.mainApp.NatDyNetUIFigure.Visible = 'on';
            app.ISCFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: RunBtn
        function RunBtnPushed(app, event)
            if yjj_isUiobjNull(app)
                return 
            end

            try
                patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                app.ax.Title.String = 'initializing...';
                app.ax.Visible = 'on';
                app.ProgrssBarLabel.Visible = 'on';
                app.runned = true;
                
                yjj_ctrlUiobjEdfBtnCkb(app, 'off')

                computeISC(app.e_InputDirEditField.Value, app.subdirPrefixEditField.Value, app.e_GMmaskfileEditField.Value, app.e_SavedDirEditField.Value, app);

                msg = 'Congratulations,ISC is finished';
                title = 'Finished';
                msgbox(msg, title)
                yjj_ctrlUiobjEdfBtnCkb(app, 'on')

                app.ax.Title.String = 'Congratulations! ISC Result have been saved in "saveDir"';
            catch ME
                app.RunBtn.Enable = "on";
                app.InputDirBtn.Enable = "on";
                app.GMBtn.Enable = 'on';
                app.SavedDirBtn.Enable = "on";
                app.subdirPrefixEditField.Editable = "on";

                if ME.message
                    msg = ME.message;
                else
                    msg = 'There is an error in computing ISC';
                end
                title = 'Error';
                msgbox(msg, title, 'error');
                app.ax.Title.String = 'Error';
            end


        end

        % Value changed function: subdirPrefixEditField
        function subdirPrefixEditFieldValueChanged(app, event)
            value = app.subdirPrefixEditField.Value;
            if ~isempty(value)
                sublist = getSublistByPrefixed(app.e_InputDirEditField.Value, value);
                subnum = size(sublist,1);
                if subnum ==0
                    promptText = 'Error Input!';
                else
                    promptText = ['The number of target subjects is ' num2str(subnum)];
                end
                app.Label.Text = promptText;
            else
                app.Label.Text = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISCFigure = uifigure('Visible', 'off');
            app.ISCFigure.Name = 'ISC';
            app.ISCFigure.CloseRequestFcn = createCallbackFcn(app, @ISCFigureCloseRequest, true);
            app.RunBtn = uibutton(app.ISCFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunBtn.Text = 'Run';
            app.InputDirBtn = uibutton(app.ISCFigure, 'push');
            app.InputDirBtn.ButtonPushedFcn = createCallbackFcn(app, @InputDirBtnPushed, true);
            app.InputDirBtn.Text = 'choose';
            app.InputDirLabel = uilabel(app.ISCFigure);
            app.InputDirLabel.Text = 'Input Dir:';
            app.e_InputDirEditField = uieditfield(app.ISCFigure, 'text');
            app.e_InputDirEditField.Editable = 'off';
            app.PrefixLabel = uilabel(app.ISCFigure);
            app.PrefixLabel.Text = 'GMmask file:';
            app.e_GMmaskfileEditField = uieditfield(app.ISCFigure, 'text');
            app.e_GMmaskfileEditField.Editable = 'off';
            app.subdirPrefixLabel = uilabel(app.ISCFigure);
            app.subdirPrefixLabel.Text = 'subdir Prefix:';
            app.subdirPrefixEditField = uieditfield(app.ISCFigure, 'text');
            app.subdirPrefixEditField.ValueChangedFcn = createCallbackFcn(app, @subdirPrefixEditFieldValueChanged, true);
            app.subdirPrefixEditField.Tooltip = {'* respresent selecting all directories of Input Dir'};
            app.GMBtn = uibutton(app.ISCFigure, 'push');
            app.GMBtn.ButtonPushedFcn = createCallbackFcn(app, @GMBtnPushed, true);
            app.GMBtn.Text = 'choose';
            app.Label = uilabel(app.ISCFigure);
            app.Label.Text = '';
            app.ProgrssBarLabel = uilabel(app.ISCFigure);
            app.ProgrssBarLabel.Visible = 'off';
            app.ProgrssBarLabel.Text = 'Progrss Bar:';
            app.InterSubjectCorrelationISCLabel = uilabel(app.ISCFigure);
            app.InterSubjectCorrelationISCLabel.Text = 'InterSubject Correlation(ISC)';
            app.SavedDirEditFieldLabel = uilabel(app.ISCFigure);
            app.SavedDirEditFieldLabel.Text = 'Saved Dir:';
            app.e_SavedDirEditField = uieditfield(app.ISCFigure, 'text');
            app.e_SavedDirEditField.Editable = 'off';
            app.SavedDirBtn = uibutton(app.ISCFigure, 'push');
            app.SavedDirBtn.ButtonPushedFcn = createCallbackFcn(app, @SavedDirBtnButtonPushed, true);
            app.SavedDirBtn.Text = 'choose';
            % Show the figure after all components are created
            app.ISCFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISC_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ISCFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISCFigure)
        end
    end
end