classdef ISMTD_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISMTDUIFigure           matlab.ui.Figure
        TREditField             matlab.ui.control.EditField
        TREditFieldLabel        matlab.ui.control.Label
        ISAMethodLabel          matlab.ui.control.Label
        LOO_checked             matlab.ui.control.CheckBox
        regressLOO_checked      matlab.ui.control.CheckBox
        MTDwsizeEditField       matlab.ui.control.EditField
        MTDwsizeEditFieldLabel  matlab.ui.control.Label
        ParametersLabel         matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ProgrssBarLabel         matlab.ui.control.Label
        SaveDirBtn              matlab.ui.control.Button
        e_SavedDirEditField     matlab.ui.control.EditField
        PrefixLabel             matlab.ui.control.Label
        e_InputDirEditField     matlab.ui.control.EditField
        InputDirLabel           matlab.ui.control.Label
        InputDirBtn             matlab.ui.control.Button
        RunBtn                  matlab.ui.control.Button
    end


    properties (Access = public)
        mainApp % 主界面对象
        ax % 进度条对象
        title
        method
        isCreateAx %作为一个flag，判断进度条对象是否创建 % Description
        runned %作为一个flag，判断是否按过run
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, title, method)
            app.mainApp = caller;
            app.e_InputDirEditField.Value = pwd;
            app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, [method '-Result']);
            app.ISMTDUIFigure.Name = method;
            app.Label.Text = title;

            app.title = title;
            app.method = method;
            app.isCreateAx = false;
            app.runned = false;

            if isequal(method(1:2), 'IS')
                app.LOO_checked.Visible = "on";
                app.ISAMethodLabel.Visible = "on";
                app.regressLOO_checked.Visible = "on";
            end

            assignin('base', [method '_app'], app);

        end

        % Close request function: ISMTDUIFigure
        function ISMTDUIFigureCloseRequest(app, event)
            app.mainApp.ROIMethodButton.Enable = 'on';
            app.mainApp.ROIMethodsDropDown.Enable = "on";

            evalin("base", 'clear')
            delete(app)
        end

        % Button pushed function: InputDirBtn
        function InputDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = 0;
            end
            
            app.ISMTDUIFigure.Visible = 'off';
            app.mainApp.NatDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end
            
            app.ProgrssBarLabel.Visible = 'off';

            [inputDir] = uigetdir('*.*','Please select a inupt directory');
           if inputDir ~= 0 
                app.e_InputDirEditField.Value = string(inputDir);
            end
            app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, [app.method '-Result'] );
            app.mainApp.NatDyNetUIFigure.Visible = 'on';
            app.ISMTDUIFigure.Visible = 'on';%防止获取路径之后界面最小化

        end

        % Button pushed function: SaveDirBtn
        function SaveDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = 0;
            end
            app.ISMTDUIFigure.Visible = 'off';
            app.mainApp.NatDyNetUIFigure.Visible = 'off';
            if ~app.isCreateAx
                initializeProgress(app)
            end
            [savedDir] = uigetdir('*.*', 'Please select a saved directory');
            if  savedDir ~= 0 
                app.e_SavedDirEditField.Value = string(savedDir);
            end

            app.mainApp.NatDyNetUIFigure.Visible = 'on';
            app.ISMTDUIFigure.Visible = 'on';%防止获取路径之后界面最小化

        end

        % Value changed function: regressLOO_checked
        function regressLOO_checkedValueChanged(app, event)
            app.LOO_checked.Value = 0;
        end

        % Value changed function: LOO_checked
        function LOO_checkedValueChanged(app, event)
            app.regressLOO_checked.Value = 0;
        end

        % Button pushed function: RunBtn
        function RunBtnPushed(app, event)
            if yjj_isUiobjNull(app)
                return 
            end

            try
                %创建进度条对象
                if ~app.isCreateAx
                    initializeProgress(app)
                end
                patch(app.ax, [0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                app.ax.Title.String = 'initializing...';
                app.ax.Visible = 'on';
                app.ProgrssBarLabel.Visible = 'on';
                app.runned = true;
                drawnow
                yjj_ctrlUiobjEdfBtnCkb(app, 'off')

                inputDir = app.e_InputDirEditField.Value;
                savedDir = app.e_SavedDirEditField.Value;
                params.methodType = app.method;
                params.MTDwsize = str2double(app.MTDwsizeEditField.Value);
                params.TR = str2double(app.TREditField.Value);
                if isequal(app.method(1:2), 'IS')
                    if app.regressLOO_checked.Value
                        params.ISA_type = 'regressLOO';
                    end
                    if app.LOO_checked.Value
                        params.ISA_type = 'LOO';
                    end
                end
                NDN_ISMTD(inputDir, savedDir, params, app);

                msg = ['Congratulations, ' app.method ' is finished'];
                title = 'Finished';
                msgbox(msg, title)
                yjj_ctrlUiobjEdfBtnCkb(app, 'on')
                app.ax.Title.String = ['Congratulations! ' app.method ' Result have been saved in "saveDir"'] ;
            catch ME
               yjj_ctrlUiobjEdfBtnCkb(app, 'on')
                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is an error in computing ' app.method];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
                app.ax.Title.String = 'Error';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISMTDUIFigure = uifigure('Visible', 'off');
            app.ISMTDUIFigure.CloseRequestFcn = createCallbackFcn(app, @ISMTDUIFigureCloseRequest, true);
            app.RunBtn = uibutton(app.ISMTDUIFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunBtn.Text = 'Run';
            app.InputDirBtn = uibutton(app.ISMTDUIFigure, 'push');
            app.InputDirBtn.ButtonPushedFcn = createCallbackFcn(app, @InputDirBtnPushed, true);
            app.InputDirBtn.Text = 'choose';
            app.InputDirLabel = uilabel(app.ISMTDUIFigure);
            app.InputDirLabel.Text = 'Input Dir:';
            app.e_InputDirEditField = uieditfield(app.ISMTDUIFigure, 'text');
            app.e_InputDirEditField.Editable = 'off';
            app.PrefixLabel = uilabel(app.ISMTDUIFigure);
            app.PrefixLabel.Text = 'Saved Dir:';
            app.e_SavedDirEditField = uieditfield(app.ISMTDUIFigure, 'text');
            app.e_SavedDirEditField.Editable = 'off';
            app.SaveDirBtn = uibutton(app.ISMTDUIFigure, 'push');
            app.SaveDirBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveDirBtnPushed, true);
            app.SaveDirBtn.Text = 'choose';
            app.ProgrssBarLabel = uilabel(app.ISMTDUIFigure);
            app.ProgrssBarLabel.Visible = 'off';
            app.ProgrssBarLabel.Text = 'Progrss Bar:';
            app.Label = uilabel(app.ISMTDUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ISMTDUIFigure);
            app.Label_2.Text = '';
            app.ParametersLabel = uilabel(app.ISMTDUIFigure);
            app.ParametersLabel.Text = 'Parameters:';
            app.MTDwsizeEditFieldLabel = uilabel(app.ISMTDUIFigure);
            app.MTDwsizeEditFieldLabel.Text = 'MTDwsize:';
            app.MTDwsizeEditField = uieditfield(app.ISMTDUIFigure, 'text');
            app.MTDwsizeEditField.Value = '5';
            app.regressLOO_checked = uicheckbox(app.ISMTDUIFigure);
            app.regressLOO_checked.ValueChangedFcn = createCallbackFcn(app, @regressLOO_checkedValueChanged, true);
            app.regressLOO_checked.Visible = 'off';
            app.regressLOO_checked.Tooltip = {'ISCAP perform a higher spatiotemporal consistency in the analysis results than CAP.'};
            app.regressLOO_checked.Text = 'regressLOO';
            app.regressLOO_checked.Value = true;
            app.LOO_checked = uicheckbox(app.ISMTDUIFigure);
            app.LOO_checked.ValueChangedFcn = createCallbackFcn(app, @LOO_checkedValueChanged, true);
            app.LOO_checked.Visible = 'off';
            app.LOO_checked.Text = 'LOO';
            app.ISAMethodLabel = uilabel(app.ISMTDUIFigure);
            app.ISAMethodLabel.Visible = 'off';
            app.ISAMethodLabel.Text = 'ISA-Method:';
            app.TREditFieldLabel = uilabel(app.ISMTDUIFigure);
            app.TREditFieldLabel.Text = 'TR:';
            app.TREditField = uieditfield(app.ISMTDUIFigure, 'text');
            % Show the figure after all components are created
            app.ISMTDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISMTD_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ISMTDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISMTDUIFigure)
        end
    end
end