classdef NDN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NatDyNetUIFigure              matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        STEP3ClusteringPlottingLabel  matlab.ui.control.Label
        ClusteringPlottingButton      matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        GMMethodButton                matlab.ui.control.Button
        ROIMethodButton               matlab.ui.control.Button
        GMvoxelMethodsDropDown        matlab.ui.control.DropDown
        GMvoxelMethodsDropDownLabel   matlab.ui.control.Label
        ROIMethodsDropDown            matlab.ui.control.DropDown
        ROIMethodsDropDownLabel       matlab.ui.control.Label
        STEP2ChoosemethodLabel        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        SimulateROITimeCourseButton   matlab.ui.control.Button
        STEP1AcquireROITCLabel        matlab.ui.control.Label
        ExtractROITimeCourseButton    matlab.ui.control.Button
        NatDyNetNaturalisticDynamicsNetworkLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        ISCapp % Description
        ISCAPapp
        CAPapp
        SFCapp
        GetROITCapp
        SWFCapp
        ISSWFCapp
        DCCapp
        ISDCCapp
        FLSapp
        ISFLSapp
        ISGLKFapp
        GLKFapp
        ISMTDapp
        MTDapp
        Cluster_Plotapp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('')
            disp('Welcome to using NatDyNet--Nature Dynamics Network toolbox')
        end

        % Close request function: NatDyNetUIFigure
        function NatDyNetUIFigureCloseRequest(app, event)
            dalete(app.ISCapp )
            dalete(app.ISCAPapp)
            dalete(app.CAPapp)
            dalete(app.SFCapp)
            dalete(app.GetROITCapp)
            dalete(app.SWFCapp)
            dalete(app.ISSWFCapp)
            dalete(app.DCCapp)
            dalete(app.ISDCCapp)
            dalete(app.FLSapp)
            dalete(app.ISFLSapp)
            dalete(app.ISGLKFapp)
            dalete(app.GLKFapp)
            dalete(app.ISMTDapp)
            dalete(app.MTDapp)
            dalete(app.Cluster_Plotapp)
            delete(app)
            disp('')
            disp('Thank you for using Nature Dynamics Network')
        end

        % Button pushed function: ExtractROITimeCourseButton
        function ExtractROITimeCourseButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ExtractROITimeCourseButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.GetROITCapp = GetROITC_GUI(app);
        end

        % Value changed function: GMvoxelMethodsDropDown
        function GMvoxelMethodsDropDownValueChanged(app, event)
%             value = app.GMvoxelMethodsDropDown.Value;
%             if ~isequal(value, ' ')
%                 app.GMvoxelMethodsDropDown.Enable = "off";
%             end
%             if isequal(value, 'ISCAP')
%                 app.ISCAPapp = ISCAP_GUI(app);
%             end
%             if isequal(value, 'ISC')
%                 app.ISCapp = ISC_GUI(app);
%             end
        end

        % Value changed function: ROIMethodsDropDown
        function ROIMethodsDropDownValueChanged(app, event)
%             value = app.ROIMethodsDropDown.Value;
%             if ~isequal(value, ' ')
%                 app.ROIMethodsDropDown.Enable = "off";
%             end
%             if isequal(value, 'SFC')
%                 app.SFCapp = SFCROI_GUI(app);
%             end
        end

        % Button pushed function: GMMethodButton
        function GMMethodButtonPushed(app, event)
            value = app.GMvoxelMethodsDropDown.Value;
            if ~isequal(value, ' ')
                app.GMvoxelMethodsDropDown.Enable = "off";
                app.GMMethodButton.Enable = "off";
            else
                return
            end
                

            if isequal(value, 'ISCAP')
                app.ISCAPapp = ISCAP_GUI(app, 'InterSubject Coactivation Pattern(ISCAP)', 'ISCAP');
            end
            if isequal(value, 'CAP')
                app.CAPapp = ISCAP_GUI(app, 'Coactivation Pattern(CAP)', 'CAP');
            end
            if isequal(value, 'ISC')
                app.ISCapp = ISC_GUI(app);
            end
            
        end

        % Button pushed function: ROIMethodButton
        function ROIMethodButtonPushed(app, event)
            value = app.ROIMethodsDropDown.Value;

            if ~isequal(value, ' ')
                app.ROIMethodsDropDown.Enable = "off";
                app.ROIMethodButton.Enable = "off";
            else
                return
            end

            if isequal(value, 'SFC')
                app.SFCapp = SFCROI_GUI(app);
                return
            end
            if isequal(value, 'SWFC')
                app.SWFCapp = ISSWFC_GUI(app, 'Sliding-window functional connectivity(SWFC)', 'SWFC');
                return
            end
            if isequal(value, 'ISSWFC')
                app.ISSWFCapp = ISSWFC_GUI(app, 'Intersubject sliding-window functional connectivity(ISSWFC)', 'ISSWFC');
                return
            end
            if isequal(value, 'DCC')
                app.DCCapp = ISDCC_GUI(app, 'Dynamic condition correlation(DCC)', 'DCC');
                return
            end
            if isequal(value, 'ISDCC')
                app.ISDCCapp = ISDCC_GUI(app, 'Intersubject dynamic condition correlation(ISDCC)', 'ISDCC');
                return
            end
            if isequal(value, 'FLS')
                app.FLSapp = ISFLS_GUI(app, 'Flexible least square(FLS)', 'FLS');
                return
            end
            if isequal(value, 'ISFLS')
                app.ISFLSapp = ISFLS_GUI(app, 'Intersubject flexible least square(ISFLS)', 'ISFLS');
                return
            end
            if isequal(value, 'GLKF')
                app.GLKFapp = ISGLKF_GUI(app, 'General linear Kalman filter(GLKF)', 'GLKF');
                return
            end
            if isequal(value, 'ISGLKF')
                app.ISGLKFapp = ISGLKF_GUI(app, 'Intersubject general linear Kalman filter(ISGLKF)', 'ISGLKF');
                return
            end
            if isequal(value, 'MTD')
                app.MTDapp = ISMTD_GUI(app, 'Multiplication of Temporal Derivatives(MTD)', 'MTD');
                return
            end
            if isequal(value, 'ISMTD')
                app.ISMTDapp = ISMTD_GUI(app, 'Intersubject multiplication of Temporal Derivatives(ISMTD)', 'ISMTD');
                return
            end
        end

        % Button pushed function: ClusteringPlottingButton
        function ClusteringPlottingButtonPushed(app, event)
            app.ClusteringPlottingButton.Enable = 'off';
            app.Cluster_Plotapp = Cluster_Plotting_GUI(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NatDyNetUIFigure = uifigure('Visible', 'off');
            app.NatDyNetUIFigure.Name = 'NatDyNet';
            app.NatDyNetUIFigure.CloseRequestFcn = createCallbackFcn(app, @NatDyNetUIFigureCloseRequest, true);
            app.NatDyNetNaturalisticDynamicsNetworkLabel = uilabel(app.NatDyNetUIFigure);
            app.NatDyNetNaturalisticDynamicsNetworkLabel.Text = 'NatDyNet--Naturalistic Dynamics Network';
            app.Panel = uipanel(app.NatDyNetUIFigure);
            app.ExtractROITimeCourseButton = uibutton(app.Panel, 'push');
            app.ExtractROITimeCourseButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractROITimeCourseButtonPushed, true);
            app.ExtractROITimeCourseButton.Text = 'Extract ROI Time Course';
            app.STEP1AcquireROITCLabel = uilabel(app.Panel);
            app.STEP1AcquireROITCLabel.Text = 'STEP 1:  Acquire ROI TC';
            app.SimulateROITimeCourseButton = uibutton(app.Panel, 'push');
            app.SimulateROITimeCourseButton.Enable = 'off';
            app.SimulateROITimeCourseButton.Text = 'Simulate ROI Time Course';
            app.Panel_2 = uipanel(app.NatDyNetUIFigure);
            app.STEP2ChoosemethodLabel = uilabel(app.Panel_2);
            app.STEP2ChoosemethodLabel.Text = 'STEP 2:  Choose method';
            app.ROIMethodsDropDownLabel = uilabel(app.Panel_2);
            app.ROIMethodsDropDownLabel.Text = 'ROI Methods:';
            app.ROIMethodsDropDown = uidropdown(app.Panel_2);
            app.ROIMethodsDropDown.Items = {'SFC', 'ISSWFC', 'SWFC', 'ISDCC', 'DCC', 'ISFLS', 'FLS', 'ISGLKF', 'GLKF', 'ISMTD', 'MTD', ' '};
            app.ROIMethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIMethodsDropDownValueChanged, true);
            app.ROIMethodsDropDown.Value = ' ';
            app.GMvoxelMethodsDropDownLabel = uilabel(app.Panel_2);
            app.GMvoxelMethodsDropDownLabel.Text = 'GM voxel Methods:';
            app.GMvoxelMethodsDropDown = uidropdown(app.Panel_2);
            app.GMvoxelMethodsDropDown.Items = {'ISC', 'ISCAP', 'CAP', ' '};
            app.GMvoxelMethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @GMvoxelMethodsDropDownValueChanged, true);
            app.GMvoxelMethodsDropDown.Value = ' ';
            app.ROIMethodButton = uibutton(app.Panel_2, 'push');
            app.ROIMethodButton.ButtonPushedFcn = createCallbackFcn(app, @ROIMethodButtonPushed, true);
            app.ROIMethodButton.Text = 'choose';
            app.GMMethodButton = uibutton(app.Panel_2, 'push');
            app.GMMethodButton.ButtonPushedFcn = createCallbackFcn(app, @GMMethodButtonPushed, true);
            app.GMMethodButton.Text = 'choose';
            app.Panel_3 = uipanel(app.NatDyNetUIFigure);
            app.ClusteringPlottingButton = uibutton(app.Panel_3, 'push');
            app.ClusteringPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringPlottingButtonPushed, true);
            app.ClusteringPlottingButton.Text = 'Clustering & Plotting';
            app.STEP3ClusteringPlottingLabel = uilabel(app.Panel_3);
            app.STEP3ClusteringPlottingLabel.Text = 'STEP 3:  Clustering & Plotting';
            % Show the figure after all components are created
            app.NatDyNetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NDN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NatDyNetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NatDyNetUIFigure)
        end
    end
end